classdef pkt_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RXPacketsPanel     matlab.ui.container.Panel
        UITable_2          matlab.ui.control.Table
        TXPacketsPanel     matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        NodeDropDown       matlab.ui.control.DropDown
        NodeDropDownLabel  matlab.ui.control.Label
        UpdateButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp;
        selectedNode;
    end
    
    %%
    %
    %   TO-DO:
    %       - Create table with all events (tx and rx packets) per node
    %%

    %% TIMER
    methods (Access = private)
        function timerInit(app)
            app.timer_monitor = timer;
            
            app.timer_monitor.TimerFcn = @app.updateTables;
            app.timer_monitor.Period = 1;
            app.timer_monitor.StartDelay = 0;
            app.timer_monitor.ExecutionMode = 'fixedSpacing';
            
        end

        function timerStart(app)
            start(app.timer_monitor)
        end
        
        function timerStop(app)
            stop(app.timer_monitor);
        end

        function updateTables(app,~,~)
            app.updateTxTable();
            app.updateRxTable();
        end
    end

    %% UTILITIES
    methods (Access = private)

        % Get the number of existing nodes from the main app
        function n_nodes = getNumberOfNodes(app)
            n_nodes = length(app.maninApp.NETWORK.NODE_LIST);
        end

        % Get the node id list from the main app
        function node_id = getNodeIdList(app)

            n_nodes = app.getNumberOfNodes();

            node_id = zeros(1, n_nodes);

            for k = 1:n_nodes
                node_id(k) = app.maninApp.NETWORK.NODE_LIST(k).id;
            end
        end
    end

    %% DROPDOWN LIST
    methods (Access = private)

        function initNodeDropDown(app)
            
            % Get the number of nodes
            n_nodes = app.getNumberOfNodes();

            % Get node ids list
            node_id_list = app.getNumberOfNodes();
    
            % Create string structure
            dropdown_options = strings(n_nodes,1);

            for k = 1:n_nodes
                dropdown_options(k) = string(node_id_list);
            end
    
            % Assign dropdown options
            app.NodeDropDown.Items = dropdown_options;

            % Initialize dropdown with the first node
            app.NodeDropDown.Value = app.NodeDropDown.Items(1);

        end
    
        function getNodeFromDropDown(app)
            
            app.selectedNode = double(app.NodeDropDown.Value);

        end
    end

    %% TX TABLE
    methods (Access = public)

        function initTxTable(app)

        end

        function updateTxTable(app)

        end
    end

    %% RX TABLE
    methods (Access = public)
        
        function initRxTable(app)

        end

        function updateRxTable(app)

        end

    end

    %% CLOSE APP
    methods (Access = public)

        function closeAppWindow(app)
            app.timerStop();
            delete(app);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % Get information about main app
            app.mainApp = mainApp;

            % Initialize node selection dropdown list
            app.initNodeDropDown();
            
            % Initialize Tx Table
            app.initTxTable();
            
            % Initialize Rx Table
            app.initRxTable();

            % Initialize Timer
            app.timerInit();

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Update the selected node
            app.getNodeFromDropDown();

            % Update Tx Table
            app.updateTxTable();

            % Update Rx Table
            app.updateRxTable();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.closeAppWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4'};
            app.NodeDropDown.Value = '1';
            app.TXPacketsPanel = uipanel(app.UIFigure);
            app.TXPacketsPanel.Title = 'TX Packets';
            app.UITable = uitable(app.TXPacketsPanel);
            app.UITable.ColumnName = {'tx_time'; 'pkt_id'; 'src'; 'dest'};
            app.UITable.RowName = {};
            app.RXPacketsPanel = uipanel(app.UIFigure);
            app.RXPacketsPanel.Title = 'RX Packets';
            app.UITable_2 = uitable(app.RXPacketsPanel);
            app.UITable_2.ColumnName = {'rx_time'; 'src'; 'pkt_id'; 'dest'};
            app.UITable_2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pkt_table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end